classdef multiphysicsModel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        RightPanel                   matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        D_FTab_2                     matlab.ui.container.Tab
        Axes3D_F                     matlab.ui.control.UIAxes
        D_RTab                       matlab.ui.container.Tab
        Axes3D_R                     matlab.ui.control.UIAxes
        D_FTab                       matlab.ui.container.Tab
        Axes2D_Force                 matlab.ui.control.UIAxes
        D_RTab_2                     matlab.ui.container.Tab
        Axes2D_R                     matlab.ui.control.UIAxes
        InfoTab                      matlab.ui.container.Tab
        InfoTextArea                 matlab.ui.control.TextArea
        TextAreaLabel                matlab.ui.control.Label
        LeftPanel                    matlab.ui.container.Panel
        multiplethetaEditField       matlab.ui.control.EditField
        multiplethetaEditFieldLabel  matlab.ui.control.Label
        StatusLabel                  matlab.ui.control.Label
        RValueLabel                  matlab.ui.control.Label
        RTitleLabel                  matlab.ui.control.Label
        ForceValueLabel              matlab.ui.control.Label
        ForceTitleLabel              matlab.ui.control.Label
        OutputGroupLabel             matlab.ui.control.Label
        ResetButton                  matlab.ui.control.Button
        PlotButton                   matlab.ui.control.Button
        CalcButton                   matlab.ui.control.Button
        DispField                    matlab.ui.control.NumericEditField
        Disp001mmLabel               matlab.ui.control.Label
        ThetaField                   matlab.ui.control.NumericEditField
        Theta02Label                 matlab.ui.control.Label
    end

    
    properties (Access = public)
        TitleLabel           matlab.ui.control.Label
        SubtitleLabel        matlab.ui.control.Label
        DividerLabel         matlab.ui.control.Label

        % Input Group
        InputGroupLabel      matlab.ui.control.Label
        ThetaLabel           matlab.ui.control.Label
        ThetaUnitLabel       matlab.ui.control.Label
        DispLabel            matlab.ui.control.Label
        DispUnitLabel        matlab.ui.control.Label

        % Parameter Group
        ParamGroupLabel      matlab.ui.control.Label
        kLabel               matlab.ui.control.Label
    end
    
    properties (Access = private)
        % Current computed values
        CurrentForce = 0;
        CurrentR     = 0;
    end

    
    methods (Access = private)

        function F = computeForce(app, theta_deg, disp)
            % Force = f(theta, displacement)
            % Numerically stable Softplus model
            disp = disp.*1000
        
            z = -1.0127940326 ...
                + 0.1796901007.*theta_deg ...
                + 0.2703894104.*disp ...
                + 0.1216594455.*theta_deg.^2 ...
                + 0.0006357018.*disp.^2 ...
                - 0.0144948852.*theta_deg.*disp;
        
            % F = max(z,0) + log1p(exp(-abs(z)));
            F = log(1 + exp(z));
        
        end

        function R = computeR(app, theta_deg, F)
            % R = f(theta, Force)
            if F<0
                F = 0;
            end
            theta_deg0 = 0.5;
            R0 = 0.28145 .* (1 + 0.0080 .* (theta_deg-theta_deg0));
            A = 0.8295 .* exp(1.80 .* (theta_deg-theta_deg0));
            B = 2.16903 .* exp(-0.50 .* (theta_deg-theta_deg0));
            C = 0.04714 .* (1 + 0.30 .* (theta_deg-theta_deg0));
            a = 0.1;
            R = R0 + A .* exp(-B .* F) + C .* F.^(-a);

        end

        function updateAllPlots(app)
            theta_vec = linspace(0, 2, 60);   % 0 - 2°
            disp_vec  = linspace(0, 0.1, 60);  % 0 – 0.1mm
            [THETA, DISP] = meshgrid(theta_vec, disp_vec);
            F_surf = app.computeForce(THETA, DISP);

            F_vec = linspace(0,15,60);
            [THETA, Force] = meshgrid(theta_vec, F_vec);
            R_surf = app.computeR(THETA, Force);

            % ---- 3D surface (Force) ----
            ax3F = app.Axes3D_F;
            cla(ax3F);
            surf(ax3F, THETA, DISP, F_surf, 'EdgeAlpha', 0.15, ...
                 'FaceAlpha', 0.9, 'FaceColor', 'interp');
            colormap(ax3F, parula);
            cb = colorbar(ax3F);
            cb.Label.String = 'Force (N)';
            xlabel(ax3F, '\theta (°)',  'FontWeight','bold');
            ylabel(ax3F, 'Displacement (mm)', 'FontWeight','bold');
            zlabel(ax3F, 'Force (N)',   'FontWeight','bold');
            title(ax3F,  'Force = f(\theta, Displacement)', 'FontSize', 12, 'FontWeight','bold');
            view(ax3F, -35, 30);
            grid(ax3F, 'on');
            ax3F.Box = 'on';

            % Mark current operating point
            theta_now = app.ThetaField.Value;
            disp_now  = app.DispField.Value;
            F_now     = app.CurrentForce;
            hold(ax3F, 'on');
            plot3(ax3F, theta_now, disp_now, F_now, 'r*', ...
                  'MarkerSize', 12, 'LineWidth', 2, ...
                  'DisplayName', 'Current Point');
            legend(ax3F, 'Location', 'best');
            hold(ax3F, 'off');

            % ---- 3D surface (R) ----
            ax3R = app.Axes3D_R;
            cla(ax3R);
            surf(ax3R, THETA, Force, R_surf, 'EdgeAlpha', 0.15, ...
                 'FaceAlpha', 0.9, 'FaceColor', 'interp');
            colormap(ax3R, parula);
            cb = colorbar(ax3R);
            cb.Label.String = 'Force (g)';
            xlabel(ax3R, '\theta (°)',  'FontWeight','bold');
            ylabel(ax3R, 'Force (g)', 'FontWeight','bold');
            zlabel(ax3R, 'R (Ω)',   'FontWeight','bold');
            title(ax3R,  'R = f(\theta, Force)', 'FontSize', 12, 'FontWeight','bold');
            view(ax3R, -35, 30);
            grid(ax3R, 'on');
            ax3R.Box = 'on';

            % Mark current operating point
            theta_now = app.ThetaField.Value;
            F_now     = app.CurrentForce;
            R_now = app.CurrentR;

            hold(ax3R, 'on');
            plot3(ax3R, theta_now, F_now, R_now, 'r*', ...
                  'MarkerSize', 12, 'LineWidth', 2, ...
                  'DisplayName', 'Current Point');
            legend(ax3R, 'Location', 'best');
            hold(ax3R, 'off');


            % ---- 2D Force vs disp (multiple theta) ----
            ax2f = app.Axes2D_Force;
            cla(ax2f);
            hold(ax2f, 'on');
            % theta_lines = [0,0.5,1,1.5];
            str = app.multiplethetaEditField.Value;
            strCells = strsplit(str, ',');
            theta_lines = str2double(strCells);
            colors2    = lines(numel(theta_lines));
            for i = 1:numel(theta_lines)
                t = theta_lines(i);
                F_line = app.computeForce(t, disp_vec);
                plot(ax2f, disp_vec, F_line, '-', ...
                     'Color', colors2(i,:), 'LineWidth', 1.8, ...
                     'DisplayName', sprintf('t = %g °', t));
            end
            % Current point
            plot(ax2f, disp_now, F_now, 'r*', 'MarkerSize', 12, ...
                 'LineWidth', 2, 'DisplayName', 'Current Point');
            xline(ax2f, disp_now, '--r', 'LineWidth', 1, 'HandleVisibility','off');
            yline(ax2f, F_now,     '--r', 'LineWidth', 1, 'HandleVisibility','off');
            hold(ax2f, 'off');
            xlabel(ax2f, 'disp (mm)',  'FontWeight','bold');
            ylabel(ax2f, 'Force (g)',   'FontWeight','bold');
            title(ax2f,  'Force vs disp (parametric \theta)', 'FontSize',11,'FontWeight','bold');
            legend(ax2f, 'Location', 'best', 'FontSize', 8);
            grid(ax2f, 'on');
            ax2f.Box = 'on';

            % ---- 2D R vs disp (multiple theta) ----
            ax2r = app.Axes2D_R;
            cla(ax2r);
            hold(ax2r, 'on');
            for i = 1:numel(theta_lines)
                t = theta_lines(i);
                F_line = app.computeForce(t, disp_vec);
                R_line = app.computeR(disp_vec, F_line);
                plot(ax2r, disp_vec, R_line, '-', ...
                     'Color', colors2(i,:), 'LineWidth', 1.8, ...
                     'DisplayName', sprintf('t = %g °', t));
            end
            R_now = app.CurrentR;
            plot(ax2r, disp_now, R_now, 'r*', 'MarkerSize', 12, ...
                 'LineWidth', 2, 'DisplayName', 'Current Point');
            xline(ax2r, disp_now, '--r', 'LineWidth', 1, 'HandleVisibility','off');
            yline(ax2r, R_now,     '--r', 'LineWidth', 1, 'HandleVisibility','off');
            hold(ax2r, 'off');
            xlabel(ax2r, 'disp(mm)',    'FontWeight','bold');
            ylabel(ax2r, 'R (g)',         'FontWeight','bold');
            title(ax2r,  'R vs disp  (parametric \theta)', 'FontSize',11,'FontWeight','bold');
            legend(ax2r, 'Location', 'best', 'FontSize', 8);
            grid(ax2r, 'on');
            ax2r.Box = 'on';
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcButton
        function CalcButtonPushed(app, event)
            try
                theta = app.ThetaField.Value;
                disp  = app.DispField.Value;

                % Validate inputs
                if theta < 0 || theta >= 90
                    app.StatusLabel.Text       = '⚠  theta must be in [0°, 90°)';
                    app.StatusLabel.FontColor  = [0.85 0.33 0.10];
                    return;
                end
                if disp < 0
                    app.StatusLabel.Text       = '⚠  Displacement must be ≥ 0';
                    app.StatusLabel.FontColor  = [0.85 0.33 0.10];
                    return;
                end

                F = app.computeForce(theta, disp);
                R = app.computeR(theta, F);

                app.CurrentForce = F;
                app.CurrentR     = R;

                app.ForceValueLabel.Text = sprintf('%.4f', F);
                app.RValueLabel.Text     = sprintf('%.4f', R);

                app.StatusLabel.Text      = '✔  Calculation successful';
                app.StatusLabel.FontColor = [0.13 0.55 0.13];

            catch ME
                app.StatusLabel.Text      = ['✘  Error: ' ME.message];
                app.StatusLabel.FontColor = [0.8 0.0 0.0];
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            % Run calculation first then update all plots
            app.CalcButtonPushed([]);
            app.updateAllPlots();
            % Switch to 3D tab
            app.TabGroup.SelectedTab = app.D_FTab_2;
            app.StatusLabel.Text      = '✔  Plots updated';
            app.StatusLabel.FontColor = [0.13 0.55 0.13];
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.ThetaField.Value      = 0.5;
            app.DispField.Value       = 0.02;

            app.ForceValueLabel.Text  = '—';
            app.RValueLabel.Text      = '—';
            app.CurrentForce          = 0;
            app.CurrentR              = 0;
            app.StatusLabel.Text      = 'Parameters reset to defaults.';
            app.StatusLabel.FontColor = [0.3 0.3 0.3];
            % Clear plots
            cla(app.Axes3D_F);
            cla(app.Axes2D_Force);
            cla(app.Axes2D_R);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.Theta02Label = uilabel(app.LeftPanel);
            app.Theta02Label.Text = 'Theta(0-2°)';
            app.ThetaField = uieditfield(app.LeftPanel, 'numeric');
            app.Disp001mmLabel = uilabel(app.LeftPanel);
            app.Disp001mmLabel.Text = 'Disp(0-0.1mm)';
            app.DispField = uieditfield(app.LeftPanel, 'numeric');
            app.CalcButton = uibutton(app.LeftPanel, 'push');
            app.CalcButton.ButtonPushedFcn = createCallbackFcn(app, @CalcButtonPushed, true);
            app.CalcButton.Text = 'CalcButton';
            app.PlotButton = uibutton(app.LeftPanel, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'PlotButton';
            app.ResetButton = uibutton(app.LeftPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'ResetButton';
            app.OutputGroupLabel = uilabel(app.LeftPanel);
            app.OutputGroupLabel.Text = 'OutputGroup：';
            app.ForceTitleLabel = uilabel(app.LeftPanel);
            app.ForceTitleLabel.Text = 'ForceTitle';
            app.ForceValueLabel = uilabel(app.LeftPanel);
            app.ForceValueLabel.Text = 'ForceValue';
            app.RTitleLabel = uilabel(app.LeftPanel);
            app.RTitleLabel.Text = 'RTitle';
            app.RValueLabel = uilabel(app.LeftPanel);
            app.RValueLabel.Text = 'RValue';
            app.StatusLabel = uilabel(app.LeftPanel);
            app.StatusLabel.Text = 'Status';
            app.multiplethetaEditFieldLabel = uilabel(app.LeftPanel);
            app.multiplethetaEditFieldLabel.Text = 'multiple theta';
            app.multiplethetaEditField = uieditfield(app.LeftPanel, 'text');
            app.multiplethetaEditField.Value = '0,0.5,1,1.5';
            app.RightPanel = uipanel(app.UIFigure);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.D_FTab_2 = uitab(app.TabGroup);
            app.D_FTab_2.Title = '3D_F';
            app.Axes3D_F = uiaxes(app.D_FTab_2);
            title(app.Axes3D_F, 'Title')
            xlabel(app.Axes3D_F, 'X')
            ylabel(app.Axes3D_F, 'Y')
            zlabel(app.Axes3D_F, 'Z')
            app.D_RTab = uitab(app.TabGroup);
            app.D_RTab.Title = '3D_R';
            app.Axes3D_R = uiaxes(app.D_RTab);
            title(app.Axes3D_R, 'Title')
            xlabel(app.Axes3D_R, 'X')
            ylabel(app.Axes3D_R, 'Y')
            zlabel(app.Axes3D_R, 'Z')
            app.D_FTab = uitab(app.TabGroup);
            app.D_FTab.Title = '2D_F';
            app.Axes2D_Force = uiaxes(app.D_FTab);
            title(app.Axes2D_Force, 'Title')
            xlabel(app.Axes2D_Force, 'X')
            ylabel(app.Axes2D_Force, 'Y')
            zlabel(app.Axes2D_Force, 'Z')
            app.D_RTab_2 = uitab(app.TabGroup);
            app.D_RTab_2.Title = '2D_R';
            app.Axes2D_R = uiaxes(app.D_RTab_2);
            title(app.Axes2D_R, 'Title')
            xlabel(app.Axes2D_R, 'X')
            ylabel(app.Axes2D_R, 'Y')
            zlabel(app.Axes2D_R, 'Z')
            app.InfoTab = uitab(app.TabGroup);
            app.InfoTab.Title = 'Info';
            app.TextAreaLabel = uilabel(app.InfoTab);
            app.TextAreaLabel.Text = 'Text Area';
            app.InfoTextArea = uitextarea(app.InfoTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = multiphysicsModel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end